classdef GUIcomponents < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SYSTEMMESSAGESPanel   matlab.ui.container.Panel
        TextArea              matlab.ui.control.TextArea
        CardTable             matlab.ui.control.Image
        SettingsButton        matlab.ui.control.Image
        MapButton             matlab.ui.control.Image
        PlayerTable0          matlab.ui.control.Image
        TaskBarFiller         matlab.ui.control.Label
        TaskBarEmpty          matlab.ui.control.Image
        Deck                  matlab.ui.control.Image
        HelpButton            matlab.ui.control.Image
        CardPile              matlab.ui.control.Image
        PlayerTable4          matlab.ui.control.Image
        PlayerTable3          matlab.ui.control.Image
        PlayerTable2          matlab.ui.control.Image
        PlayerTable1          matlab.ui.control.Image
        Task8                 matlab.ui.control.Image
        Task7                 matlab.ui.control.Image
        Task6                 matlab.ui.control.Image
        Task5                 matlab.ui.control.Image
        Task4                 matlab.ui.control.Image
        Task3                 matlab.ui.control.Image
        Task2                 matlab.ui.control.Image
        Task1                 matlab.ui.control.Image
        ShuffleButton         matlab.ui.control.Button
        PickUpCard            matlab.ui.control.Button
        PLAYCARDButton        matlab.ui.control.StateButton
        TimeDisplay           matlab.ui.control.Label
        PlayedTask            matlab.ui.control.Image
        STARTSCREENPanel      matlab.ui.container.Panel
        startImage            matlab.ui.control.Image
        STARTButton           matlab.ui.control.Button
        NumberOfPlayersPanel  matlab.ui.container.Panel
        Num5Button            matlab.ui.control.Button
        Num4Button            matlab.ui.control.Button
        Num3Button            matlab.ui.control.Button
        Num2Button            matlab.ui.control.Button
    end

    
    properties (Access = private)
        myPlayerNum    % Player 1 or Player 2?
        otherPlayerNum % Number of other player whose turn is after yours
        channelID      % ThingSpeak Channel ID
        writeKey       % Channel Write API Key
        readKey        % Channel Read API Key
        readDelay      % wait 5 seconds between ThingSpeak Channel Reads
        writeDelay     % wait 5 seconds between ThingSpeak Channel Writings
        taskDeck       % shuffle deck of task cards (no imposter cards in 2 player)
        myLastDrawnCard % card that this player most recently drew
        otherPlayerLastDrawnCard % card that the other player most recently drew
        currentHand    % max 8 cards in a hand
        
        playerNum      % number of players playing, chosen by the user
        
        LogStart       % Logical starting value, only starts if num of players is chosen and start button is clicked
         
        crewImageList %list of image indexes based on the number of players, otherwise value stored is 0
        
        index_crewname_array % list of crewmates
        card_pile_array      % originally empty, whenever a player puts down a card it is removed from taskDeck
                             % and add it to card_pile_array
        crewDeck      % "Deck" of crewmate image files
        playerColorList % list of all the current players colors in the dialog box
        playerIndex    % index of player color chosen in the dialog box
        selectedPlayer %whether the user selected/did not select a value in the dialog box
        taskCounter % number of tasks clicked on
        
        drawnCard     % card that is drawn from the deck
        widthT       % task bar
        playValue % value of play button
    end

    methods (Access = public)
        
        %NOT DONE YET
        function app = setImages1(app)
            %if there are less than 8 cards in the hand (obv more than 5 given)
            %then make the spaces for 6 or 7 or 8 cards set to transparent
            if((app.player1.currentHand)<8)
                imageArray1 = repmat({'transparent.gif'},1, 60);
                
            end
        end
        
        %clears any data from previous games in thingspeak channel before you
        %start a new game
        %made by Radhika :0
        function [] = ClearThingSpeakChannel(app)
%             thingSpeakWrite(app.channelID, 'Fields', 1, 'Values','WriteKey',app.writeKey);
        end
        
        %made by Radhika :0
        function [] = ShuffleDeck(app)
            %randperm returns a row vector 
            %containing a random permutation of the integers from 1 to n
            app.taskDeck = app.taskDeck(randperm(length(app.taskDeck)));
            
        end
        
        %made by Radhika :0, Nira :)
        function [] = CreateTaskDeck(app)
            index_tasks_array = repmat({[1],[2],[3],[4],[5],[6],[7],[8],[9],[10],[11],[12],[13],[14],[15]},1,4);
            index_array = {1:60};
            prefix_name = cell(1,60);
            imagename_array = cell(1,60);
            for i = 1:60
                prefix_name{i} = strjoin(["ProjTask", index_tasks_array(i)],'');
                imagename_array{i}= strjoin([prefix_name{i}, '.gif'],'');
            end
            app.taskDeck = struct('index', index_array, 'index_tasks_array', index_tasks_array, ...
                 'imagename_array', imagename_array);
            app.card_pile_array = [];
            app.ShuffleDeck;

             %to access the card project task names
            %for i = 1:60
                %duplicates of task cards are every 15 cards i.e.
                %ProjTask1.gif is card 1 and card 16
                %A_cell = struct2cell(app.taskDeck);
                %D = A_cell(:,:,i);
                %D(3);

             %end 

        end
        %made by Radhika and Nira :0 :)
        function [] = CreateHand(app)
            %we initialized the first 5 cards users have when they
            %start the game
            app.currentHand = app.taskDeck(1:5);
            app.Task1.ImageSource = app.currentHand(1).imagename_array;
            app.Task2.ImageSource  = app.currentHand(2).imagename_array;
            app.Task3.ImageSource  = app.currentHand(3).imagename_array;
            app.Task4.ImageSource  = app.currentHand(4).imagename_array;
            app.Task5.ImageSource  = app.currentHand(5).imagename_array;
            % we can set the cells the 5 cards were previously located
            % within taskDeck to empty.
            for i = 1:5
                app.taskDeck(i).imagename_array = [];
            end
            
        end


        %NOT COMPLETED
        function DeckClicked(app, event)
            app.myLastDrawnCard = app.DrawCard();
            
            app.UpdateStatus(app.GetCardName(app.myLastDrawnCard),...
                app.GetCardName(app.otherPlayerLastDrawnCard));
            
            set(app.PickUpCard,'Enable','off');
            
            app.SendDataToOtherPlayer();
            
            %At this point, player 1 is already displaying both cards 
            %so the cards variables can be set to blank
            if(app.myPlayerNum ==2)
                app.ClearPlayerCards();
            end
            app.waitForOtherPlayer();
            
            %At this point, player 1 is already displaying both cards 
            %so the cards variables can be set to blank
            if(app.myPlayerNum ==1)
                app.ClearPlayerCards();
            end
            app.waitForOtherPlayer();
        end
        
        %made by Radhika :0 + Nira :)
        function [] = DrawCard(app)
            app.drawnCard = app.taskDeck(end);
            if length(app.currentHand)<=8
                app.currentHand=[app.currentHand app.drawnCard];
                app.taskDeck(end)= [];
            else
                msgbox("You cannot add another card!")
            end
            
        end
        
        %made by Radhika :0
        function [] = ClearPlayerCards(app)
            app.myLastDrawnCard.task = 0;
            app.otherPlayerLastDrawnCard.task = 0;
        end
        %made by Nira :)
        function [] = CreateCrewStructure(app)
            app.index_crewname_array = {['Pink'],['Red'],['Orange'],['Yellow'],['LGreen'],['Green'],['LBlue'],['Blue'],['Purple'],['Brown']};
            crew_prefix_name = cell(1,10);
            crewimagename_array = cell(1,10);
            for i = 1:10
                crew_prefix_name{i} = strjoin(['Crew', app.index_crewname_array(i)],'');
                crewimagename_array{i}= strjoin([crew_prefix_name(i),'.gif'],'');
            end
            app.crewDeck = struct('CrewImageFileName', crewimagename_array);
            
        end
        
        %made by Nira :)
        function [] = CrewImageAssign(app)
            randomNum=10;
            while randomNum==10
                randomNum=randi(10,1);
            end
            app.crewImageList=[randomNum,0,0,0,0];
            for i=2:app.playerNum
                app.crewImageList(i)=app.crewImageList(i-1)+1;
                if app.crewImageList(i)==11
                    app.crewImageList(i)=1;
                end
            end  
            
        end
        %made by Nira :) 
        function [] = ChooseImposter(app)
            app.playerColorList=[];
            for i=2:app.playerNum+1
                app.playerColorList=[app.playerColorList string({app.index_crewname_array(app.crewImageList(i-1))})];
            end
            [app.playerIndex,app.selectedPlayer]=listdlg('PromptString',{'EMERGENCY MEETING, Choose the color who you think is IMPOSTER'},'SelectionMode','single','ListString',app.playerColorList);

        end
         
        
        function [] = replaceCard(app)
            for i=1:length(app.currentHand)
                    
                    if (app.currentHand(i).imagename_array=="transparent.gif")
                        app.DrawCard;
                        
                        app.currentHand(i).imagename_array=app.currentHand(9).imagename_array;
                        app.currentHand(9).imagename_array = [];
                        switch i
                            case 1
                                app.Task1.ImageSource=app.currentHand(i).imagename_array;
                                app.Task1.Visible="on";
                                app.Task1.Enable="on";
                                
                            case 2
                                app.Task2.ImageSource=app.currentHand(i).imagename_array;
                                app.Task2.Visible="on";
                                app.Task2.Enable="on";
                                
                            case 3
                                app.Task3.ImageSource=app.currentHand(i).imagename_array;
                                app.Task3.Visible="on";
                                app.Task3.Enable="on";
                                
                            case 4
                                app.Task4.ImageSource=app.currentHand(i).imagename_array;
                                app.Task4.Visible="on";
                                app.Task4.Enable="on";
                                
                            case 5
                                app.Task5.ImageSource=app.currentHand(i).imagename_array;
                                app.Task5.Visible="on";
                                app.Task5.Enable="on";
                                
                            case 6
                                app.Task6.ImageSource=app.currentHand(i).imagename_array;
                                app.Task6.Visible="on";
                                app.Task6.Enable="on";
                                
                            case 7
                                app.Task7.ImageSource=app.currentHand(i).imagename_array;
                                app.Task7.Visible="on";
                                app.Task7.Enable="on";
                                
                            case 8
                                app.Task8.ImageSource=app.currentHand(i).imagename_array;
                                app.Task8.Visible="on";
                                app.Task8.Enable="on";
                            otherwise
                                app.currentHand(9).imagename_array = [];
                        end
                        
                        break;
                    end
            end
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global taskCounter;
            taskCounter = 1;
            app.myPlayerNum = 1;
            app.otherPlayerNum = 2;

             % CHANNEL ID 
            app.channelID = 1233879;

             % WRITE API KEY
            app.writeKey = 'MTUX56SDZ2BJDG5J';

             % READ API KEY
            app.readKey = '28HCRPFLSZZGCERE';

            app.readDelay = 5;
            app.writeDelay = 1;
            app.ClearThingSpeakChannel();
            app.PlayedTask.ImageSource="transparent.gif";
            app.myLastDrawnCard.task = 0;
            app.otherPlayerLastDrawnCard.task = 0;
            app.STARTSCREENPanel.Visible = "on";
            %app.STARTSCREENPanel.Visible = "on";
            %app.STARTSCREENPanel.Visible = "on";
            app.playValue=0;
            app.TaskBarFiller.Position=[87,594,500,37];
            app.Task6.Enable="off";
            app.Task7.Enable="off";
            app.Task8.Enable="off";
            app.Task6.ImageSource='transparent.gif';
            app.Task7.ImageSource='transparent.gif';
            app.Task8.ImageSource='transparent.gif';
            %set(app.Player1RedButton,'Enable','off');
            %set(app.Player2BlueButton,'Enable','off');
            set(app.STARTButton,'Enable','off');
            app.CreateTaskDeck;            
            app.CreateHand;
            
            if(app.myPlayerNum == 1)
                set(app.PickUpCardPushed, 'Enable','on');
                %app.CreateTaskDeck;
                %app.CreateHand;
            %app.myPlayerNum = 2
            else 
                app.WaitForOtherPlayer();
                app.UpdateStatus(app.GetCardName(app.myLastDrawnCard),...
                    app.GetCardName(app.otherPlayerLastDrawnCard));
                set(app.PickUpCardPushed,'Enable','on');
            end
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if app.LogStart==true
                app.STARTSCREENPanel.Visible = "off";
                msgbox("Welcome to the ship! You are a CREWMATE.");
                
            else
                errordlg("PLEASE CLICK THE NUMBER OF PLAYERS.")
            end
        end

        % Button pushed function: Num2Button
        function Num2ButtonPushed(app, event)
            app.playerNum=2;
            app.LogStart=true;
            set(app.STARTButton,'Enable','on');
            app.CrewImageAssign
            app.CreateCrewStructure();
            Crew_cell = struct2cell(app.crewDeck);
            switch(app.playerNum)
                case 2
                    app.PlayerTable0.ImageSource=string(Crew_cell(:,:,app.crewImageList(1)));
                    app.PlayerTable1.ImageSource=string(Crew_cell(:,:,app.crewImageList(2)));
                    app.PlayerTable2.ImageSource='transparent.gif';
                    app.PlayerTable3.ImageSource='transparent.gif';
                    app.PlayerTable4.ImageSource='transparent.gif';
                    
            end
        end

        % Button pushed function: Num3Button
        function Num3ButtonPushed(app, event)
            set(app.STARTButton,'Enable','off');
            app.playerNum=3;
            app.LogStart=false;
            errordlg("THREE PLAYER MODE IS NOT ENABLED YET. PLEASE BE PATIENT. Choose 2 player mode please.");
            app.CrewImageAssign
            app.CreateCrewStructure();
            Crew_cell = struct2cell(app.crewDeck);
            switch(app.playerNum)
                case 3
                    app.PlayerTable0.ImageSource=string(Crew_cell(:,:,app.crewImageList(1)));
                    app.PlayerTable1.ImageSource=string(Crew_cell(:,:,app.crewImageList(2)));
                    app.PlayerTable2.ImageSource=string(Crew_cell(:,:,app.crewImageList(3)));
                    app.PlayerTable3.ImageSource='transparent.gif';
                    app.PlayerTable4.ImageSource='transparent.gif';
                    
            end
        end

        % Button pushed function: Num4Button
        function Num4ButtonPushed(app, event)
            set(app.STARTButton,'Enable','off');
            app.playerNum=4;
            app.LogStart=false;
            errordlg("FOUR PLAYER MODE IS NOT ENABLED YET. PLEASE BE PATIENT. Choose 2 player mode please.");
            app.CrewImageAssign
            app.CreateCrewStructure();
            Crew_cell = struct2cell(app.crewDeck);
            switch(app.playerNum)
                case 4
                    app.PlayerTable0.ImageSource=string(Crew_cell(:,:,app.crewImageList(1)));
                    app.PlayerTable1.ImageSource=string(Crew_cell(:,:,app.crewImageList(2)));
                    app.PlayerTable2.ImageSource=string(Crew_cell(:,:,app.crewImageList(3)));
                    app.PlayerTable3.ImageSource='transparent.gif';
                    app.PlayerTable4.ImageSource='transparent.gif';
            end
                 
        end

        % Button pushed function: Num5Button
        function Num5ButtonPushed(app, event)
            set(app.STARTButton,'Enable','off');
            app.playerNum=5;
            app.LogStart=false;
            errordlg("FIVE PLAYER MODE IS NOT ENABLED YET. PLEASE BE PATIENT. Choose 2 player mode please.");
            app.CrewImageAssign
            app.CreateCrewStructure();
            Crew_cell = struct2cell(app.crewDeck);
            switch(app.playerNum)
                case 5
                    app.PlayerTable0.ImageSource=string(Crew_cell(:,:,app.crewImageList(1)));
                    app.PlayerTable1.ImageSource=string(Crew_cell(:,:,app.crewImageList(2)));
                    app.PlayerTable2.ImageSource=string(Crew_cell(:,:,app.crewImageList(3)));
                    app.PlayerTable3.ImageSource=string(Crew_cell(:,:,app.crewImageList(4)));
                    app.PlayerTable4.ImageSource=string(Crew_cell(:,:,app.crewImageList(5)));
            end
        end

        % Button pushed function: PickUpCard
        function PickUpCardPushed(app, event)
            app.DrawCard;
            app.myLastDrawnCard=app.drawnCard;
            
            if length(app.currentHand)==6
                msgbox("You picked up a card!")
                app.Task6.Enable="on";
                app.Task6.ImageSource  = app.currentHand(6).imagename_array;
            elseif length(app.currentHand)==7
                msgbox("You picked up a card!")
                app.Task6.Enable="on";
                app.Task7.Enable="on";
                app.Task6.ImageSource  = app.currentHand(6).imagename_array;
                app.Task7.ImageSource  = app.currentHand(7).imagename_array;
            elseif length(app.currentHand)==8
                msgbox("You picked up a card!")
                app.Task6.Enable="on";
                app.Task7.Enable="on";
                app.Task8.Enable="on";
                app.Task6.ImageSource  = app.currentHand(6).imagename_array;
                app.Task7.ImageSource  = app.currentHand(7).imagename_array;
                app.Task8.ImageSource  = app.currentHand(8).imagename_array;
            else
                app.replaceCard;
            end
                
            
            %need to implement the drawn card being placed in the hand, all
            %the cards move down a spot
            
        end

        % Image clicked function: Task1
        function Task1ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            app.playValue = app.PLAYCARDButton.Value;
             if app.playValue==0
                msgbox(string(taskCounter)+"/1")
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                
                app.PlayedTask.ImageSource =app.currentHand(1).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task1.ImageSource = "transparent.gif";
                app.currentHand(1).imagename_array="transparent.gif";
                app.Task1.Visible = "on";
                app.Task1.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                played_card = app.taskDeck(1).imagename_array;
                app.taskDeck(1).imagename_array = [];
                app.card_pile_array = [app.card_pile_array played_card];
                app.playValue=1;
                
            else
                app.Task1.Position = [767 3 128 195];
                app.Task1.Enable = "on";
                app.playValue=1;
            end
            
        end

        % Image clicked function: Task2
        function Task2ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            app.playValue = app.PLAYCARDButton.Value;
             if app.playValue==0
                msgbox(string(taskCounter)+"/2")
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                app.PlayedTask.ImageSource =app.currentHand(2).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task2.ImageSource = "transparent.gif";
                app.currentHand(2).imagename_array="transparent.gif";
                app.Task2.Visible = "on";
                app.Task2.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                played_card = app.taskDeck(2).imagename_array;
                app.taskDeck(2).imagename_array = [];
                app.card_pile_array = [app.card_pile_array played_card];
            else
                app.Task2.Position = [648 5 120 183];
                app.Task2.Enable = "on";
                app.playValue=1;
            end
        end

        % Image clicked function: Task3
        function Task3ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            app.playValue = app.PLAYCARDButton.Value;
             if app.playValue==0
                msgbox(string(taskCounter)+"/3")
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                app.PlayedTask.ImageSource =app.currentHand(3).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task3.ImageSource = "transparent.gif";
                app.currentHand(3).imagename_array="transparent.gif";
                app.Task3.Visible = "on";
                app.Task3.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                played_card = app.taskDeck(3).imagename_array;
                app.taskDeck(3).imagename_array = [];
                app.card_pile_array = [app.card_pile_array played_card];
            else
                app.Task3.Position = [525 5 124 186];
                
                app.Task3.Enable = "on";
            end
        end

        % Image clicked function: Task4
        function Task4ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            app.playValue = app.PLAYCARDButton.Value;
             if app.playValue==0
                msgbox(string(taskCounter)+"/4")
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                app.PlayedTask.ImageSource =app.currentHand(4).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task4.ImageSource = "transparent.gif";
                app.currentHand(4).imagename_array="transparent.gif";
                app.Task4.Visible = "on";
                app.Task4.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                played_card = app.taskDeck(4).imagename_array;
                app.taskDeck(4).imagename_array = [];
                app.card_pile_array = [app.card_pile_array played_card];
            else
                app.Task4.Position = [402 6 124 182];
                
                app.Task4.Enable = "on";
            end
        end

        % Image clicked function: Task5
        function Task5ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            app.playValue = app.PLAYCARDButton.Value;
             if app.playValue==0
                msgbox(string(taskCounter)+"/5")
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                app.PlayedTask.ImageSource =app.currentHand(5).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task5.ImageSource = "transparent.gif";
                app.currentHand(5).imagename_array="transparent.gif";
                app.Task5.Visible = "on";
                app.Task5.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                played_card = app.taskDeck(5).imagename_array;
                app.taskDeck(5).imagename_array = [];
                app.card_pile_array = [app.card_pile_array played_card];
            else
                app.Task5.Position = [283 4 120 180];
                
                app.Task5.Enable = "on";
            end
        end

        % Image clicked function: Task6
        function Task6ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            app.playValue = app.PLAYCARDButton.Value;
             if app.playValue==0
                msgbox(string(taskCounter)+"/6")
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                app.PlayedTask.ImageSource =app.currentHand(6).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task6.ImageSource = "transparent.gif";
                app.currentHand(6).imagename_array="transparent.gif";
                app.Task6.Visible = "on";
                app.Task6.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                played_card = app.taskDeck(6).imagename_array;
                app.taskDeck(6).imagename_array = [];
                app.card_pile_array = [app.card_pile_array played_card];
            else
                app.Task6.Position = [110 6 139 188];
                
                app.Task6.Enable = "on";
            end
        end

        % Image clicked function: Task7
        function Task7ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            app.playValue = app.PLAYCARDButton.Value;
             if app.playValue==0
                msgbox(string(taskCounter)+"/7")
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                app.PlayedTask.ImageSource =app.currentHand(7).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task7.ImageSource = "transparent.gif";
                app.currentHand(7).imagename_array="transparent.gif";
                app.Task7.Visible = "on";
                app.Task7.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                played_card = app.taskDeck(7).imagename_array;
                app.taskDeck(7).imagename_array = [];
                app.card_pile_array = [app.card_pile_array played_card];
            else
                app.Task7.Position = [35 5 159 188];
                
                app.Task7.Enable = "on";
            end
            
        end

        % Image clicked function: Task8
        function Task8ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            app.playValue = app.PLAYCARDButton.Value;
             if app.playValue==0
                msgbox(string(taskCounter)+"/7")
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                app.PlayedTask.ImageSource =app.currentHand(8).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task8.ImageSource = "transparent.gif";
                app.currentHand(8).imagename_array="transparent.gif";
                app.Task8.Visible = "on";
                app.Task8.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                played_card = app.taskDeck(8).imagename_array;
                app.taskDeck(8).imagename_array = [];
                app.card_pile_array = [app.card_pile_array played_card];
            else
                app.Task8.Position = [-7 4 159 188];
                app.Task8.Enable = "on";
            end
            
            
        end

        % Button pushed function: ShuffleButton
        function ShuffleButtonPushed(app, event)
            app.ShuffleDeck;
            msgbox("YOU SHUFFLED THE DECK :)")
        end

        % Value changed function: PLAYCARDButton
        function PLAYCARDButtonValueChanged(app, event)
            
             if app.playValue==0
                
                app.playValue=1;
            else
                msgbox("NOW CLICK ON THE CARD YOU WANT TO PLAY AGAIN")
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SYSTEMMESSAGESPanel = uipanel(app.UIFigure);
            app.SYSTEMMESSAGESPanel.Title = 'SYSTEM MESSAGES ';
            app.TextArea = uitextarea(app.SYSTEMMESSAGESPanel);
            app.TextArea.Value = {'Disaster!'};
            app.CardTable = uiimage(app.UIFigure);
            app.CardTable.ImageSource = 'CardTable.gif';
            app.SettingsButton = uiimage(app.UIFigure);
            app.SettingsButton.ImageSource = 'SettingsButton.gif';
            app.MapButton = uiimage(app.UIFigure);
            app.MapButton.ImageSource = 'MapButton.gif';
            app.PlayerTable0 = uiimage(app.UIFigure);
            app.PlayerTable0.ImageSource = 'CrewPurple.gif';
            app.TaskBarFiller = uilabel(app.UIFigure);
            app.TaskBarFiller.Text = '.';
            app.TaskBarEmpty = uiimage(app.UIFigure);
            app.TaskBarEmpty.ImageSource = 'TaskBarTransparent.gif';
            app.Deck = uiimage(app.UIFigure);
            app.Deck.ImageSource = 'DeckPile.gif';
            app.HelpButton = uiimage(app.UIFigure);
            app.HelpButton.ImageSource = 'HelpButton.gif';
            app.CardPile = uiimage(app.UIFigure);
            app.CardPile.ImageSource = 'DeckCardsPlayed.gif';
            app.PlayerTable4 = uiimage(app.UIFigure);
            app.PlayerTable4.ImageSource = 'CrewGreen.gif';
            app.PlayerTable3 = uiimage(app.UIFigure);
            app.PlayerTable3.ImageSource = 'CrewBrown.gif';
            app.PlayerTable2 = uiimage(app.UIFigure);
            app.PlayerTable2.ImageSource = 'CrewPink.gif';
            app.PlayerTable1 = uiimage(app.UIFigure);
            app.PlayerTable1.ImageSource = 'CrewBlue.gif';
            app.Task8 = uiimage(app.UIFigure);
            app.Task8.ImageClickedFcn = createCallbackFcn(app, @Task8ImageClicked, true);
            app.Task8.ImageSource = 'ProjTask6.gif';
            app.Task7 = uiimage(app.UIFigure);
            app.Task7.ImageClickedFcn = createCallbackFcn(app, @Task7ImageClicked, true);
            app.Task7.ImageSource = 'ProjTask7.gif';
            app.Task6 = uiimage(app.UIFigure);
            app.Task6.ImageClickedFcn = createCallbackFcn(app, @Task6ImageClicked, true);
            app.Task6.ImageSource = 'ProjTask8.gif';
            app.Task5 = uiimage(app.UIFigure);
            app.Task5.ImageClickedFcn = createCallbackFcn(app, @Task5ImageClicked, true);
            app.Task5.ImageSource = 'ProjTask1.gif';
            app.Task4 = uiimage(app.UIFigure);
            app.Task4.ImageClickedFcn = createCallbackFcn(app, @Task4ImageClicked, true);
            app.Task4.ImageSource = 'ProjTask2.gif';
            app.Task3 = uiimage(app.UIFigure);
            app.Task3.ImageClickedFcn = createCallbackFcn(app, @Task3ImageClicked, true);
            app.Task3.ImageSource = 'ProjTask3.gif';
            app.Task2 = uiimage(app.UIFigure);
            app.Task2.ImageClickedFcn = createCallbackFcn(app, @Task2ImageClicked, true);
            app.Task2.ImageSource = 'ProjTask4.gif';
            app.Task1 = uiimage(app.UIFigure);
            app.Task1.ImageClickedFcn = createCallbackFcn(app, @Task1ImageClicked, true);
            app.Task1.ImageSource = 'ProjTask5.gif';
            app.ShuffleButton = uibutton(app.UIFigure, 'push');
            app.ShuffleButton.ButtonPushedFcn = createCallbackFcn(app, @ShuffleButtonPushed, true);
            app.ShuffleButton.Icon = 'ShuffleCard.gif';
            app.ShuffleButton.Text = '';
            app.PickUpCard = uibutton(app.UIFigure, 'push');
            app.PickUpCard.ButtonPushedFcn = createCallbackFcn(app, @PickUpCardPushed, true);
            app.PickUpCard.Icon = 'DeckButton.gif';
            app.PickUpCard.Text = '';
            app.PLAYCARDButton = uibutton(app.UIFigure, 'state');
            app.PLAYCARDButton.ValueChangedFcn = createCallbackFcn(app, @PLAYCARDButtonValueChanged, true);
            app.PLAYCARDButton.Icon = 'CrewWhite.gif';
            app.PLAYCARDButton.Text = {'PLAY'; 'CARD'};
            app.PLAYCARDButton.Value = true;
            app.TimeDisplay = uilabel(app.UIFigure);
            app.TimeDisplay.Text = '  TIME LEFT:';
            app.PlayedTask = uiimage(app.UIFigure);
            app.STARTSCREENPanel = uipanel(app.UIFigure);
            app.STARTSCREENPanel.Title = 'START SCREEN';
            app.STARTSCREENPanel.Visible = 'off';
            app.startImage = uiimage(app.STARTSCREENPanel);
            app.startImage.ImageSource = 'AmongUsTitlePage.gif';
            app.STARTButton = uibutton(app.STARTSCREENPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.NumberOfPlayersPanel = uipanel(app.STARTSCREENPanel);
            app.NumberOfPlayersPanel.Title = 'Number Of Players';
            app.Num5Button = uibutton(app.NumberOfPlayersPanel, 'push');
            app.Num5Button.ButtonPushedFcn = createCallbackFcn(app, @Num5ButtonPushed, true);
            app.Num5Button.Text = '5';
            app.Num4Button = uibutton(app.NumberOfPlayersPanel, 'push');
            app.Num4Button.ButtonPushedFcn = createCallbackFcn(app, @Num4ButtonPushed, true);
            app.Num4Button.Text = '4';
            app.Num3Button = uibutton(app.NumberOfPlayersPanel, 'push');
            app.Num3Button.ButtonPushedFcn = createCallbackFcn(app, @Num3ButtonPushed, true);
            app.Num3Button.Text = '3';
            app.Num2Button = uibutton(app.NumberOfPlayersPanel, 'push');
            app.Num2Button.ButtonPushedFcn = createCallbackFcn(app, @Num2ButtonPushed, true);
            app.Num2Button.Text = '2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIcomponents

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end