classdef GUIcomponentsWITHSOUNDV2_Alyssa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        minutesLabel              matlab.ui.control.Label
        secondsLabel              matlab.ui.control.Label
        numSeconds_Label          matlab.ui.control.Label
        SYSTEMMESSAGESPanel       matlab.ui.container.Panel
        TextArea                  matlab.ui.control.TextArea
        CardTable                 matlab.ui.control.Image
        MapButton                 matlab.ui.control.Image
        PlayerTable0              matlab.ui.control.Image
        TaskBarFiller             matlab.ui.control.Label
        TaskBarEmpty              matlab.ui.control.Image
        Deck                      matlab.ui.control.Image
        HelpButton                matlab.ui.control.Image
        CardPile                  matlab.ui.control.Image
        PlayerTable4              matlab.ui.control.Image
        PlayerTable3              matlab.ui.control.Image
        PlayerTable2              matlab.ui.control.Image
        PlayerTable1              matlab.ui.control.Image
        Task8                     matlab.ui.control.Image
        Task7                     matlab.ui.control.Image
        Task6                     matlab.ui.control.Image
        Task5                     matlab.ui.control.Image
        Task4                     matlab.ui.control.Image
        Task3                     matlab.ui.control.Image
        Task2                     matlab.ui.control.Image
        Task1                     matlab.ui.control.Image
        ShuffleButton             matlab.ui.control.Button
        DrawButton                matlab.ui.control.Button
        PLAYCARDButton            matlab.ui.control.StateButton
        TimeDisplay               matlab.ui.control.Label
        PlayedTask                matlab.ui.control.Image
        numMinutes_Label          matlab.ui.control.Label
        RESULTSCREENPanel         matlab.ui.container.Panel
        WinImage                  matlab.ui.control.Image
        LoseImage                 matlab.ui.control.Image
        Quit                      matlab.ui.control.Image
        PlayAgain                 matlab.ui.control.Image
        Player1Image              matlab.ui.control.Image
        Player2Image              matlab.ui.control.Image
        QuitButton                matlab.ui.control.Button
        VOLUMEButtonGroup         matlab.ui.container.ButtonGroup
        ONButton                  matlab.ui.control.RadioButton
        OFFButton                 matlab.ui.control.RadioButton
        STARTSCREENPanel          matlab.ui.container.Panel
        startImage                matlab.ui.control.Image
        STARTButton               matlab.ui.control.Button
        NumberOfPlayersPanel      matlab.ui.container.Panel
        Num5Button                matlab.ui.control.Button
        Num4Button                matlab.ui.control.Button
        Num3Button                matlab.ui.control.Button
        Num2Button                matlab.ui.control.Button
        WhoPlaysFirstButtonGroup  matlab.ui.container.ButtonGroup
        Player1Button             matlab.ui.control.ToggleButton
        Player2Button             matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        myPlayerNum    % Player 1 or Player 2?
        otherPlayerNum % Number of other player whose turn is after yours
        channelID      % ThingSpeak Channel ID
        writeKey       % Channel Write API Key
        readKey        % Channel Read API Key
        readDelay      % wait 5 seconds between ThingSpeak Channel Reads
        writeDelay     % wait 5 seconds between ThingSpeak Channel Writings
        taskDeck       % shuffle deck of task cards (no imposter cards in 2 player)
        myLastDrawnCard % card that this player most recently drew
        otherPlayerLastDrawnCard % card that the other player most recently drew
        currentHand    % max 8 cards in a hand
        
        playerNum      % number of players playing, chosen by the user
        
        LogStart       % Logical starting value, only starts if num of players is chosen and start button is clicked
        
        crewImageList %list of image indexes based on the number of players, otherwise value stored is 0
        
        index_crewname_array % list of crewmates
        card_pile_array      % originally empty, whenever a player puts down a card it is removed from taskDeck
        % and add it to card_pile_array
        crewDeck      % "Deck" of crewmate image files
        playerColorList % list of all the current players colors in the dialog box
        playerIndex   % index of player color chosen in the dialog box
        selectedPlayer %whether the user selected/did not select a value in the dialog box
        taskCounter % number of tasks clicked on
        
        drawnCard     % card that is drawn from the deck
        widthT       % task bar
        playValue % value of play button
        getDeckFileNames %gets the file names in the deck into one array
        score    %score taskbar
        timerobj %timer object
        played_card %file name of card played
        taskDeckString %concatenated string of the deck
        dataTable
        
    end
    
    methods (Access = public)
        %THINGSPEAK FUNCTIONS
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function [] = WaitForOtherPlayer(app)
            playerWhoSentMsg = 0;
            
            while playerWhoSentMsg ~= app.otherPlayerNum
                % read a data table from the channel
                dataTable = thingSpeakRead(app.channelID, 'Fields', 1, 'ReadKey', ...
                    app.readKey, 'OutputFormat','Table');
                
                % access the entry in the table containing the data string
                dataString = string(dataTable.dataString(1));
                
                % to help with debugging
                disp("I am player " + num2str(app.myPlayerNum) ...
                    + " and I just read this data from the ThingSpeak channel:");
                disp(dataString);
                
                % split data string into separate file names
                dataParts = split(strip(dataString));
                
                % the first number in the string should
                % always be the number of the player who
                % last wrote the data
                playerWhoSentMsg = str2num(dataParts(1));
                
                % delay before trying to read the online channel again
                pause(app.readDelay);
            end
            
            % The second number represent the card
            % most recently drawn by the other player.
            app.otherPlayerCard.task = str2num(dataParts(2));
            
            % The numbers from the 3rd onwards represent
            % the cards remaining in the deck.
            app.taskDeck = app.MakeTaskDeckFromString(dataParts(3:end));
        end
        % Selection changed function: WhoPlaysFirstButtonGroup
        
        %made by Radhika :0
        function [] = MakeStringFromTaskDeck(app)
            deckString = "";
            app.taskDeckString = "";
            
            for i = 1:numel(app.taskDeck)
                card = app.taskDeck(i).imagename_array;
                temp = app.taskDeckString;
                app.taskDeckString = strcat(card, temp);
                app.taskDeckString = strcat(app.taskDeckString, deckString);
            end
            disp("this is " + app.taskDeckString)
            
        end
        
        function [] = MakeTaskDeckFromString(app)
            %assemble deck from strings
            if((app.dataTable.dataString(1)) == 0)
                msgbox("you are reading from an empty channel, do nothing")
            else
                x = split(app.dataTable.dataString(1), '.gif');
                [a,b] = size(x);
                updated_index_array = {1:a-1};
                y = cell(1,60);
                for i = 1:a-1
                    y{i} =(strjoin([x(i), '.gif'],''));
                    imagename_array{i} = y{i};
                    %taskDeck(i).imagename_array
                end
                app.taskDeck = struct('index', updated_index_array, ...
                    'imagename_array', imagename_array);
                app.ShuffleDeck;
            end
            
        end
        function [] = UpdateStatus(app, myPlayedCardImageFileString, otherPlayerCardImageFileString)
            %p1CardString = "";
            %p2CardString = "";
            
            if app.myPlayerNum == 1
                app.PlayedTask.ImageSource = myPlayedCardImageFileString;
                p2CardString = otherPlayerCardImageFileString;
            else
                p1CardString = otherPlayerCardImageFileString;
                p2CardString = myPlayedCardImageFileString;
            end
            
            app.PlayedTask.ImageSource = [ p1CardString ; "Player 2: " + p2CardString];
        end
        
        %made by Radhika :0
        function [] = SendDataToOtherPlayer(app)
            pause(app.writeDelay);
            dataString = strcat(string(app.myPlayerNum), " ", ...
                string(app.myLastDrawnCard.task));
            
            deckString = app.MakeStringFromTaskDeck(app.taskDeck);
            dataString = strcat(dataString, deckString);
            
            disp("I sent this data to the ThingSpeak channel: ");
            disp(dataString);
            
            thingSpeakWrite(app.channelID, 'Fields', 1, 'Values', dataString ...
                ,'WriteKey', app.writeKey);
        end
        
        
        %clears any data from previous games in thingspeak channel before you
        %start a new game
        %made by Radhika :0
        function [] = ClearThingSpeakChannel(app)
            thingSpeakWrite(app.channelID, 'Fields', 1, 'Values', 0,'WriteKey',app.writeKey);
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        
        %TIMER FUNCTION, made by Radhika :0
        function countdown(app, mins, secs)
            %90secs total
            totalSecs = secs + mins*60;
            %1 minute
            numMins = floor(totalSecs/60);
            app.numMinutes_Label.Text = string(numMins);
            %30 secs
            numSecs = mod(totalSecs,60);
            app.numSeconds_Label.Text = string(numSecs);
            
            app.timerobj = timer('TimerFcn',@updateDisplay,...
                'ExecutionMode', 'fixedRate', 'Period', 1);
            secsElapsed = 0;
            start(app.timerobj);
            
            function updateDisplay(varargin)
                secsElapsed = secsElapsed + 1;
                %When time runs out stop the timer
                if (totalSecs-secsElapsed == 0)
                    if((app.numSeconds_Label.Text == '1') & (app.numMinutes_Label.Text == '0'))
                        app.numSeconds_Label.Text = string(0);
                    end
                    stop(app.timerobj);
                    delete(app.timerobj);
                    errordlg("Time is up.", "Oh no!");
                    app.RESULTSCREENPanel.Visible="on"
                    app.RESULTSCREENPanel.Enable="on"
                    app.LoseImage=true
                    app.blowingSound; 
                    
                else
                    if((numSecs - mod(secsElapsed,60) > 0) )
                        app.numSeconds_Label.Text = string(numSecs - mod(secsElapsed,60));
                        
                        
                    elseif (numSecs - mod(secsElapsed,60) == 0)
                        numMins = floor(totalSecs/60)-1;
                        app.numMinutes_Label.Text = string(numMins);
                        totalSecs = 59 + (numMins*60 );
                        numSecs = mod(totalSecs,60);
                        secsElapsed = 0;
                        app.numSeconds_Label.Text = string(numSecs);
                    else
                        numSecs = 0;
                        app.numSeconds_Label.Text = string(numSecs);
                    end
                    
                    
                end
            end
        end
        
        %made by Radhika :0
        function [] = ShuffleDeck(app)
            %randperm returns a row vector
            %containing a random permutation of the integers from 1 to n
            app.taskDeck = app.taskDeck(randperm(length(app.taskDeck)));
            
        end
        
        %made by Radhika :0, Nira :)
        function [] = CreateTaskDeck(app)
            index_tasks_array = repmat({[1],[2],[3],[4],[5],[6],[7],[8],[9],[10],[11],[12],[13],[14],[15]},1,4);
            index_array = {1:60};
            prefix_name = cell(1,60);
            imagename_array = cell(1,60);
            for i = 1:60
                prefix_name{i} = strjoin(["ProjTask", index_tasks_array(i)],'');
                imagename_array{i}= strjoin([prefix_name{i}, '.gif'],'');
            end
            app.taskDeck = struct('index', index_array, 'index_tasks_array', index_tasks_array, ...
                'imagename_array', imagename_array);
            app.card_pile_array = [];
            app.ShuffleDeck;
            
            %to access the card project task names
            %for i = 1:60
            %duplicates of task cards are every 15 cards i.e.
            %ProjTask1.gif is card 1 and card 16
            %A_cell = struct2cell(app.taskDeck);
            %D = A_cell(:,:,i);
            %D(3);
            
            %end
            
        end
        %made by Radhika and Nira :0 :)
        function [] = CreateHand(app)
            %we initialized the first 5 cards users have when they
            %start the game
            app.currentHand = app.taskDeck(1:5);
            app.Task1.ImageSource = app.currentHand(1).imagename_array;
            app.Task2.ImageSource  = app.currentHand(2).imagename_array;
            app.Task3.ImageSource  = app.currentHand(3).imagename_array;
            app.Task4.ImageSource  = app.currentHand(4).imagename_array;
            app.Task5.ImageSource  = app.currentHand(5).imagename_array;
            % we can set the cells the 5 cards were previously located
            % within taskDeck to empty.
            for i = 1:5
                app.taskDeck(i).imagename_array = [];
            end
            
            
        end


%         %NOT COMPLETED
%         function DeckClicked(app)
%             app.myLastDrawnCard = app.DrawCard();
%             
%             app.UpdateStatus(app.GetCardName(app.myLastDrawnCard),...
%                 app.GetCardName(app.otherPlayerLastDrawnCard));
%             
%             set(app.DrawButton,'Enable','off');
%             
%             app.SendDataToOtherPlayer();
%             
%             %At this point, player 1 is already displaying both cards
%             %so the cards variables can be set to blank
%             if(app.myPlayerNum ==2)
%                 app.ClearPlayerCards();
%             end
%             app.waitForOtherPlayer();
%             
%             %At this point, player 1 is already displaying both cards
%             %so the cards variables can be set to blank
%             if(app.myPlayerNum ==1)
%                 app.ClearPlayerCards();
%             end
%             app.waitForOtherPlayer();
%         end
%         
        %made by Radhika :0 + Nira :)
        function [] = DrawCard(app)
            %             if (isempty(app.taskDeck))
            %                 app.taskDeck=app.card_pile_array;
            %                 app.drawnCard = app.taskDeck(end);
            %             else
            app.drawnCard = app.taskDeck(end);
            if length(app.currentHand)<=8
                app.currentHand=[app.currentHand app.drawnCard];
                app.taskDeck(end)= [];
            else
                msgbox("You cannot add another card!")
            end
            
        end
        
        %made by Radhika :0
        function [] = ClearPlayerCards(app)
            app.myLastDrawnCard.task = 0;
            app.otherPlayerLastDrawnCard.task = 0;
        end
        %made by Nira :)
        function [] = CreateCrewStructure(app)
            app.index_crewname_array = {['Pink'],['Red'],['Orange'],['Yellow'],['LGreen'],['Green'],['LBlue'],['Blue'],['Purple'],['Brown']};
            crew_prefix_name = cell(1,10);
            crewimagename_array = cell(1,10);
            for i = 1:10
                crew_prefix_name{i} = strjoin(['Crew', app.index_crewname_array(i)],'');
                crewimagename_array{i}= strjoin([crew_prefix_name(i),'.gif'],'');
            end
            app.crewDeck = struct('CrewImageFileName', crewimagename_array);
            
        end
        
        %made by Nira :)
        function [] = CrewImageAssign(app)
            randomNum=10;
            while randomNum==10
                randomNum=randi(10,1);
            end
            app.crewImageList=[randomNum,0,0,0,0];
            for i=2:app.playerNum
                app.crewImageList(i)=app.crewImageList(i-1)+1;
                if app.crewImageList(i)==11
                    app.crewImageList(i)=1;
                end
            end
            
        end
        %made by Nira :)
        function [] = ChooseImposter(app)
            app.playerColorList=[];
            for i=2:app.playerNum+1
                app.playerColorList=[app.playerColorList string({app.index_crewname_array(app.crewImageList(i-1))})];
            end
            
            [app.playerIndex,app.selectedPlayer]=listdlg('PromptString',{'EMERGENCY MEETING, Choose the color who you think is IMPOSTER'},'SelectionMode','single','ListString',app.playerColorList);
            
        end
        
        %made by Nira :)
        function [] = replaceCard(app)
            for i=1:length(app.currentHand)
                
                if (app.currentHand(i).imagename_array=='transparent.gif')
                    app.DrawCard;
                    
                    app.currentHand(i).imagename_array=app.currentHand(9).imagename_array;
                    
                    switch i
                        case 1
                            app.Task1.ImageSource=app.currentHand(9).imagename_array;
                            app.Task1.Visible="on";
                            app.Task1.Enable="on";
                            app.currentHand(9).imagename_array = [];
                            
                        case 2
                            app.Task2.ImageSource=app.currentHand(9).imagename_array;
                            app.Task2.Visible="on";
                            app.Task2.Enable="on";
                            app.currentHand(9).imagename_array = [];
                            
                        case 3
                            app.Task3.ImageSource=app.currentHand(9).imagename_array;
                            app.Task3.Visible="on";
                            app.Task3.Enable="on";
                            app.currentHand(9).imagename_array = [];
                            
                        case 4
                            app.Task4.ImageSource=app.currentHand(9).imagename_array;
                            app.Task4.Visible="on";
                            app.Task4.Enable="on";
                            app.currentHand(9).imagename_array = [];
                            
                        case 5
                            app.Task5.ImageSource=app.currentHand(9).imagename_array;
                            app.Task5.Visible="on";
                            app.Task5.Enable="on";
                            app.currentHand(9).imagename_array = [];
                            
                        case 6
                            app.Task6.ImageSource=app.currentHand(9).imagename_array;
                            app.Task6.Visible="on";
                            app.Task6.Enable="on";
                            app.currentHand(9).imagename_array = [];
                            
                        case 7
                            app.Task7.ImageSource=app.currentHand(9).imagename_array;
                            app.Task7.Visible="on";
                            app.Task7.Enable="on";
                            app.currentHand(9).imagename_array = [];
                            
                        case 8
                            app.Task8.ImageSource=app.currentHand(9).imagename_array;
                            app.Task8.Visible="on";
                            app.Task8.Enable="on";
                            app.currentHand(9).imagename_array = [];
                        otherwise
                            app.currentHand(9).imagename_array = [];
                    end
                    
                    break;
                end
            end
            
        end
        
        %made by Alyssa O.O
        function []= taskBarCalculator(app)
            
            % create numerical Array
            makeNumArray=[]
            for i = 1:length(app.card_pile_array)
                removePrefix=strrep(app.card_pile_array(i),'ProjTask','');
                removeEnd=strrep(removePrefix,'.gif','')
                removeSpace=removeEnd(~isspace(removeEnd))
                makeNum=str2num(removeSpace)
                makeNumArray=[makeNumArray makeNum]
            end
            
            %count Repeats
            if makeNumArray==[]
                repeatedTwiceElements=[];
                repeatedFourElements=[];
            elseif  numel(makeNumArray)>=2
                edges = min(makeNumArray) : max(makeNumArray)
                [counts, values] = histcounts(makeNumArray, edges)
                repeatedTwiceElements = values(counts == 2)
                repeatedFourElements= values(counts == 4)
            else
                repeatedTwiceElements=[];
                repeatedFourElements=[];
            end
            
            
            % thingspeak 
                      if(app.myPlayerNum == 1)
                pause(app.readDelay);
                    %read old data to save 
                    app.Tscore = thingSpeakRead(app.channelID,'Fields',3,num2str('ReadKey'),app.score, 'OutputFormat',"table");
                    
                    %sends to command window for debugging
                    disp('app.score')
                    disp(app.Tscore);
             pause(app.writeDelay);
           %write to the channel
                 thingSpeakWrite(app.channelID, 'Fields',3, app.score,'WriteKey', app.writeKey);
                    app.Data=thingSpeakRead(app.writeChId); 
        app.ServerEditField.Value=app.Data 
           
                    msgbox("Player 1's TURN IS OVER")
                    
                    %player 1's turn is over
                    %change the player number to player 2
                    app.myPlayerNum = 2;                
                elseif(app.myPlayerNum == 2)
                    
                    pause(app.readDelay);
                    
                  %read old data to save 
                    app.Tscore = thingSpeakRead(app.channelID,'Fields',3,"ReadKey",app.score, 'OutputFormat',"table");
                    
                    %sends to command window for debugging
                    disp('app.score')
                    disp(app.Tscore);

                    pause(app.writeDelay);
                    
                    %write to the channel
            
                    thingSpeakWrite(app.channelID, 'Fields',3, app.score,'WriteKey', app.writeKey);
                    app.Data=thingSpeakRead(app.writeChId); 
        app.ServerEditField.Value=app.Data 
        
                    
                    app.MakeTaskDeckFromString;
                    msgbox("Player 2's TURN IS OVER")
                    
                    %player 2's turn is over
                    %change the player number to player 1
                    app.myPlayerNum = 1;
                 
                else
                    errordlg("Three to five imposter mode is being done by Kledi", "ERROR");
                end
 
            
            %add to taskbar
            repArray=[];
            for i = 1:length(app.card_pile_array)
                if ((repeatedTwiceElements~=0) | (repeatedFourElements~=0))
                    repArray=([(string(app.played_card)==string(app.card_pile_array(i)))]);
                end
                repsOfCard=find(repArray);
                app.score=app.score+5;
                app.TaskBarFiller.Position=[87,594,(app.score*5),37];
                if app.TaskBarFiller.Position==500
                    app.RESULTSCREENPanel.Visible="on"
                    app.RESULTSCREENPanel.Enable="on"
                    app.WinImage=true 
                    app.phewSound; 
                end
            end
            
            %gameOver
            
        end


        %card GUI functions made by Alyssa
        
        
        function []=cardFunction(app)
            
            makeNumArray=[]
            for i = 1:length(app.card_pile_array)
                removePrefix=strrep(app.card_pile_array(i),'ProjTask','');
                removeEnd=strrep(removePrefix,'.gif','')
                removeSpace=removeEnd(~isspace(removeEnd))
                makeNum=str2num(removeSpace)
                makeNumArray=[makeNumArray makeNum]
                %length of hand
                %             player1HandLength= length(app.player1.currentHand);
                %             player2HandLength= length(app.player2.currentHand);
                
                switch (makeNumArray(end))
                    
                    case (1)
                        
                        %                     if  player1HandLength> player2HandLength
                        %                         app.taskDeck=[app.taskDeck currentHand1(end)];
                        %                         app.currentHand1(end)= "transparent.gif";
                        %                         % remove left card from array of player 1
                        %                     elseif player2HandLength > player2HandLength
                        %                         app.taskDeck=[app.taskDeck currentHand2(end)];
                        %                         % remove left card from array of player 1
                        %                         app.currentHand2(end)= "transparent.gif";
                        %                     else
                        %                     end
                        
                    case(2)
                        %shuffle the deck
                        app.ShuffleDeck;
                        msgbox("The imposter has shuffled the deck")
                        app.score=app.score-5
                        
                    case(3)
                        %Draw an extra card automatically for player
                        app.DrawButtonPushed
                        msgbox("You have gained an extra task from the Imposter!")
                        app.score=app.score-5
                        
                    case(4)
                        %add 5 points to score
                        app.score=app.score+5
                        
                    case(5)
                        % Player turn skipped
                        msgbox("Your turn has been skipped!")
                        
                    otherwise
                        msgbox("Empty Card Played")
                end
            end
        end
        
        % made by Radhika :0
        function [] = endTurn(app)
            if (app.PlayerTable0.ImageSource~="transparent.gif")
                msgbox("It is now the next player's turn.")
                %call thinkspeak functions and transfer data to player 2
                
                if(app.myPlayerNum == 1)
                    
                    pause(app.readDelay);
                    %read old data to save other player chat message
                    app.dataTable = thingSpeakRead(app.channelID,"Fields",1,"ReadKey",app.readKey, 'OutputFormat',"table");
                    
                    %sends to command window for debugging
                    disp("Player 1 reads player 2 task deck from field 1")
                    disp(app.dataTable);
                    dataString= string(app.dataTable.dataString(1));
                    disp("This is" + " " + dataString)
                    pause(app.readDelay);
                    
                    %after player 1 reads from player 2 they make their
                    %deck into a string
                    app.MakeStringFromTaskDeck;
                    
                    %update the player 2 deck with the player 1 deck
                    updatedDeckAsString = app.taskDeckString;
                    
                    pause(app.writeDelay);
                    
                    %write your taskDeck string to the channel
                    
                    thingSpeakWrite(app.channelID, "Fields",1,"Values",updatedDeckAsString,"WriteKey",app.writeKey);
                    disp("This is" + " " + updatedDeckAsString);
                    
                    %player 1 must make task deck with string they read
                    %from player 2
                    app.MakeTaskDeckFromString;
                    msgbox("Player 1's TURN IS OVER")
                    
                    %player 1's turn is over
                    %change the player number to player 2
                    app.myPlayerNum = 2;


                elseif(app.myPlayerNum == 2)
                    
                    pause(app.readDelay);
                    
                    %read old data to save other player chat message
                    app.dataTable = thingSpeakRead(app.channelID,"Fields",1,"ReadKey",app.readKey, 'OutputFormat',"table");
                    
                    %sends to command window for debugging
                    disp("Player 2 reads player 1 task deck")
                    disp(app.dataTable);
                    dataString= string(app.dataTable.dataString(1));
                    disp("This is" + " " + dataString)
                    
                    %after player 2 reads from player 1 they make their
                    %deck into a string
                    app.MakeStringFromTaskDeck;
                    
                    %update the player 1 deck with the player 2 deck
                    updatedDeckAsString = app.taskDeckString;
                    
                    pause(app.writeDelay);
                    
                    %write your chatMessage string to the channel
                    thingSpeakWrite(app.channelID, "Fields",1,"Values",updatedDeckAsString,"WriteKey",app.writeKey);
                    disp("This is " + updatedDeckAsString)
                    
                    %player 1 must make task deck with string they read
                    %from player 2
                    app.MakeTaskDeckFromString;
                    msgbox("Player 2's TURN IS OVER")
                    
                    %player 2's turn is over
                    %change the player number to player 1
                    app.myPlayerNum = 1;
                    
                    
                else
                    errordlg("Three to five imposter mode is being done by Kledi", "ERROR");
                end
                
                app.PlayerTable0.ImageSource="transparent.gif";
            end
            
        end
        
        %         function []=soundOn(app)
        %            if app.volumeImageClicked==1
        %                app.blowingSound=[]
        %                app.clickingSound=[]
        %                app.clickSound=[]
        %                app.phewSound=[]
        %            else
        %            end
        %
        %         end
        
        
        function []=blowingSound(app)
            global blowingSound
            if app.ONButton.Value==true
                [y,Fs] = audioread("Blowing_sound.m4a");
                sound(y);
                obj = audioplayer(y, Fs);
                obj.TimerFcn = 'showSeconds';
                obj.TimerPeriod = 1;
                play(obj)
            else
            end
        end
        
        function []=phewSound(app)
            global phewSound
            if app.ONButton.Value==true
                [y,Fs] = audioread("Phew_sound.m4a");
                sound(y);
                obj = audioplayer(y, Fs);
                obj.TimerFcn = 'showSeconds';
                obj.TimerPeriod = 1;
                play(obj)
            else
            end
        end


        function []=clickingSound(app)
            global clickingSound
            %     if app.VOLUMEButtonGroup==app.ONButton
            %             if app.volumeImage.ImageSource== "soundOn.jpg"
            if app.ONButton.Value==true
                [y,Fs] = audioread("Clicking_sound.m4a");
                sound(y);
                obj = audioplayer(y, Fs);
                obj.TimerFcn = 'showSeconds';
                obj.TimerPeriod = 1;
                play(obj)
                %             else
                %             end
            else
            end
            
        end
        
        function []=clickSound(app)
            global clickSound
            if app.ONButton.Value==true
                %                if app.volumeImage.ImageSource== "soundOn.jpg"
                [y,Fs] = audioread("click_sound.m4a");
                sound(y);
                obj = audioplayer(y, Fs);
                obj.TimerFcn = 'showSeconds';
                obj.TimerPeriod = 1
                play(obj)
                % else
                %end
                %     elseif app.VOLUMEButtonGroup==app.OFFButton
            else
            end
        end
        
        % function radioButtonSelection(app,~)
        %     selectedButton=app.VOLUMEButtonGroup.ONbutton
        %     if selectedButton==app.OFFButton
        %         delete(clickingSound)
        %         delete(clickSound)
        %         delete(phewSound)
        %         delete(blowingSound)
        %     else
        %
        %     end
        % end


        %
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global taskCounter;
            taskCounter = 1;
            
            app.myPlayerNum = 1;
            app.otherPlayerNum = 2;
            
            % CHANNEL ID
            app.channelID = 1247928;
            
            % WRITE API KEY
            app.writeKey = '7W1L2F9JE0UKB4V5';
            
            % READ API KEY
            app.readKey = 'NSBPDAL38LOXWRXT';
            
            app.readDelay = 5;
            app.writeDelay = 1;
            app.ClearThingSpeakChannel();
            app.PlayedTask.ImageSource="transparent.gif";
            app.myLastDrawnCard.task = 0;
            app.otherPlayerLastDrawnCard.task = 0;
            app.STARTSCREENPanel.Visible = "on";
            app.RESULTSCREENPanel.Visible = "off";
            app.RESULTSCREENPanel.Enable = "off";
            app.playValue=0;
            app.score=0;
            app.TaskBarFiller.Position=[87,594,app.score,37];
            
            app.Task6.Enable="off";
            app.Task7.Enable="off";
            app.Task8.Enable="off";
            app.Task6.ImageSource='transparent.gif';
            app.Task7.ImageSource='transparent.gif';
            app.Task8.ImageSource='transparent.gif';

            set(app.STARTButton,'Enable','off');
            app.CreateTaskDeck;
            app.CreateHand;
            
            
        end

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            if app.LogStart==true
                app.phewSound;
                app.STARTSCREENPanel.Visible = "off";
                msgbox("Welcome to the ship! You are a CREWMATE.");
                app.countdown(6,59);
            else
                errordlg("PLEASE CLICK THE NUMBER OF PLAYERS.")
            end
        end

        % Button pushed function: Num2Button
        function Num2ButtonPushed(app, event)
            app.playerNum=2;
            app.clickSound
            app.LogStart=true;
            set(app.STARTButton,'Enable','on');
            app.CrewImageAssign
            app.CreateCrewStructure();
            %app.ChooseImposter;
            Crew_cell = struct2cell(app.crewDeck);
            switch(app.playerNum)
                case 2
                    app.PlayerTable0.ImageSource=string(Crew_cell(:,:,app.crewImageList(1)));
                    app.PlayerTable1.ImageSource=string(Crew_cell(:,:,app.crewImageList(2)));
                    app.PlayerTable2.ImageSource='transparent.gif';
                    app.PlayerTable3.ImageSource='transparent.gif';
                    app.PlayerTable4.ImageSource='transparent.gif';
                    
            end
        end

        % Button pushed function: Num3Button
        function Num3ButtonPushed(app, event)
            set(app.STARTButton,'Enable','off');
            app.playerNum=3;
            app.LogStart=false;
            app.phewSound;
            errordlg("WAITING FOR KLEDI'S IMPOSTER FUNCTIONS TO ENABLE *THREE PLAYER MODE*.  Choose 2 player mode please.");
            app.CrewImageAssign
            app.CreateCrewStructure();
            app.ChooseImposter;
            Crew_cell = struct2cell(app.crewDeck);
            switch(app.playerNum)
                case 3
                    app.PlayerTable0.ImageSource=string(Crew_cell(:,:,app.crewImageList(1)));
                    app.PlayerTable1.ImageSource=string(Crew_cell(:,:,app.crewImageList(2)));
                    app.PlayerTable2.ImageSource=string(Crew_cell(:,:,app.crewImageList(3)));
                    app.PlayerTable3.ImageSource='transparent.gif';
                    app.PlayerTable4.ImageSource='transparent.gif';
                    
            end
        end

        % Button pushed function: Num4Button
        function Num4ButtonPushed(app, event)
            set(app.STARTButton,'Enable','off');
            app.playerNum=4;
            app.phewSound;
            app.LogStart=false;
            errordlg("WAITING FOR KLEDI'S IMPOSTER FUNCTIONS TO ENABLE *FOUR PLAYER MODE*.  Choose 2 player mode please.");
            app.CrewImageAssign
            app.CreateCrewStructure();
            app.ChooseImposter;
            Crew_cell = struct2cell(app.crewDeck);
            switch(app.playerNum)
                case 4
                    app.PlayerTable0.ImageSource=string(Crew_cell(:,:,app.crewImageList(1)));
                    app.PlayerTable1.ImageSource=string(Crew_cell(:,:,app.crewImageList(2)));
                    app.PlayerTable2.ImageSource=string(Crew_cell(:,:,app.crewImageList(3)));
                    app.PlayerTable3.ImageSource='transparent.gif';
                    app.PlayerTable4.ImageSource='transparent.gif';
            end
            
        end

        % Button pushed function: Num5Button
        function Num5ButtonPushed(app, event)
            set(app.STARTButton,'Enable','off');
            app.playerNum=5;
            app.LogStart=false;
            app.phewSound;
            errordlg("WAITING FOR KLEDI'S IMPOSTER FUNCTIONS TO ENABLE *FIVE PLAYER MODE*. Choose 2 player mode please.");
            app.CrewImageAssign
            app.CreateCrewStructure();
            app.ChooseImposter;
            Crew_cell = struct2cell(app.crewDeck);
            switch(app.playerNum)
                case 5
                    app.PlayerTable0.ImageSource=string(Crew_cell(:,:,app.crewImageList(1)));
                    app.PlayerTable1.ImageSource=string(Crew_cell(:,:,app.crewImageList(2)));
                    app.PlayerTable2.ImageSource=string(Crew_cell(:,:,app.crewImageList(3)));
                    app.PlayerTable3.ImageSource=string(Crew_cell(:,:,app.crewImageList(4)));
                    app.PlayerTable4.ImageSource=string(Crew_cell(:,:,app.crewImageList(5)));
            end
        end

        % Button pushed function: DrawButton
        function DrawButtonPushed(app, event)
            app.DrawCard;
            app.myLastDrawnCard=app.drawnCard;
            app.clickSound
            if length(app.currentHand)==6
                msgbox("You picked up a card!")
                app.Task6.Enable="on";
                app.Task6.ImageSource  = app.currentHand(6).imagename_array;
            elseif length(app.currentHand)==7
                msgbox("You picked up a card!")
                app.Task6.Enable="on";
                app.Task7.Enable="on";
                app.Task6.ImageSource  = app.currentHand(6).imagename_array;
                app.Task7.ImageSource  = app.currentHand(7).imagename_array;
            elseif length(app.currentHand)==8
                msgbox("You picked up a card!")
                app.Task6.Enable="on";
                app.Task7.Enable="on";
                app.Task8.Enable="on";
                app.Task6.ImageSource  = app.currentHand(6).imagename_array;
                app.Task7.ImageSource  = app.currentHand(7).imagename_array;
                app.Task8.ImageSource  = app.currentHand(8).imagename_array;
            else
                app.replaceCard;
            end
            
            
            %need to implement the drawn card being placed in the hand, all
            %the cards move down a spot
            
        end

        % Image clicked function: Task1
        function Task1ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            
            app.playValue = app.PLAYCARDButton.Value;
            if app.playValue==0
                
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                
                app.PlayedTask.ImageSource =app.currentHand(1).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task1.ImageSource = "transparent.gif";
                app.currentHand(1).imagename_array="transparent.gif";
                app.Task1.Visible = "on";
                app.Task1.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                app.played_card = app.taskDeck(1).imagename_array;
                app.taskDeck(1).imagename_array = "";
                app.card_pile_array = [app.card_pile_array app.played_card];
                app.taskBarCalculator;
                app.clickSound;
                app.cardFunction;
                app.endTurn;
                app.playValue=1;
                
            else
                app.Task1.Position = [767 3 125 185];
                app.Task1.Enable = "on";
                app.playValue=1;
            end
            
        end

        % Image clicked function: Task2
        function Task2ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            app.playValue = app.PLAYCARDButton.Value;
            if app.playValue==0
                
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                app.PlayedTask.ImageSource =app.currentHand(2).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task2.ImageSource = "transparent.gif";
                app.currentHand(2).imagename_array="transparent.gif";
                app.Task2.Visible = "on";
                app.Task2.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                app.played_card = app.taskDeck(2).imagename_array;
                app.taskDeck(2).imagename_array = [];
                app.card_pile_array = [app.card_pile_array app.played_card];
                app.clickSound;
                app.cardFunction;
                app.taskBarCalculator;
                app.endTurn;
                app.playValue=1;
            else
                app.Task2.Position = [648 5 120 183];
                app.Task2.Enable = "on";
                app.playValue=1;
            end
        end

        % Image clicked function: Task3
        function Task3ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            app.playValue = app.PLAYCARDButton.Value;
            if app.playValue==0
                
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                app.PlayedTask.ImageSource =app.currentHand(3).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task3.ImageSource = "transparent.gif";
                app.currentHand(3).imagename_array="transparent.gif";
                app.Task3.Visible = "on";
                app.Task3.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                app.played_card = app.taskDeck(3).imagename_array;
                app.taskDeck(3).imagename_array = [];
                app.card_pile_array = [app.card_pile_array app.played_card];
                app.taskBarCalculator;
                app.clickSound;
                app.cardFunction;
                app.endTurn;
                app.playValue=1;
            else
                app.Task3.Position = [525 5 124 186];
                app.Task3.Enable = "on";
            end
        end

        % Image clicked function: Task4
        function Task4ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            app.playValue = app.PLAYCARDButton.Value;
            if app.playValue==0
                
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                app.PlayedTask.ImageSource =app.currentHand(4).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task4.ImageSource = "transparent.gif";
                app.currentHand(4).imagename_array="transparent.gif";
                app.Task4.Visible = "on";
                app.Task4.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                app.played_card = app.taskDeck(4).imagename_array;
                app.taskDeck(4).imagename_array = [];
                app.card_pile_array = [app.card_pile_array app.played_card];
                app.taskBarCalculator;
                app.clickSound;
                app.cardFunction;
                app.endTurn;
                app.playValue=1;
            else
                app.Task4.Position = [402 6 124 182];
                app.Task4.Enable = "on";
            end
        end

        % Image clicked function: Task5
        function Task5ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            app.playValue = app.PLAYCARDButton.Value;
            if app.playValue==0
                
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                app.PlayedTask.ImageSource =app.currentHand(5).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task5.ImageSource = "transparent.gif";
                app.currentHand(5).imagename_array="transparent.gif";
                app.Task5.Visible = "on";
                app.Task5.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                app.played_card = app.taskDeck(5).imagename_array;
                app.taskDeck(5).imagename_array = [];
                app.card_pile_array = [app.card_pile_array app.played_card];
                app.taskBarCalculator;
                app.clickSound;
                app.cardFunction;
                
                app.endTurn;
                app.playValue=1;
            else
                app.Task5.Position = [283 4 120 180];
                app.Task5.Enable = "on";
            end
        end

        % Image clicked function: Task6
        function Task6ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            app.playValue = app.PLAYCARDButton.Value;
            if app.playValue==0
                
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                app.PlayedTask.ImageSource =app.currentHand(6).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task6.ImageSource = "transparent.gif";
                app.currentHand(6).imagename_array="transparent.gif";
                app.Task6.Visible = "on";
                app.Task6.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                app.played_card = app.taskDeck(6).imagename_array;
                app.taskDeck(6).imagename_array = [];
                app.card_pile_array = [app.card_pile_array app.played_card];
                app.taskBarCalculator;
                app.clickSound;
                app.cardFunction;
                
                app.endTurn;
                app.playValue=1;
            else
                app.Task6.Position = [110 6 139 188];
                
                app.Task6.Enable = "on";
            end
        end

        % Image clicked function: Task7
        function Task7ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            app.playValue = app.PLAYCARDButton.Value;
            if app.playValue==0
                
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                app.PlayedTask.ImageSource =app.currentHand(7).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task7.ImageSource = "transparent.gif";
                app.currentHand(7).imagename_array="transparent.gif";
                app.Task7.Visible = "on";
                app.Task7.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                app.played_card = app.taskDeck(7).imagename_array;
                app.taskDeck(7).imagename_array = [];
                app.card_pile_array = [app.card_pile_array app.played_card];
                
                app.clickSound;
                app.cardFunction;
                app.taskBarCalculator;
                app.endTurn;
                app.playValue=1;
            else
                app.Task7.Position = [35 5 159 188];
                
                app.Task7.Enable = "on";
            end
            
        end

        % Image clicked function: Task8
        function Task8ImageClicked(app, event)
            global taskCounter;
            taskCounter = taskCounter + 1;
            
            app.playValue = app.PLAYCARDButton.Value;
            if app.playValue==0
                
                %coordinates of the card pile in middle of the table =
                %(length, width) = (124,182), coordinates: (500,250)
                app.PlayedTask.ImageSource =app.currentHand(8).imagename_array;
                app.PlayedTask.Visible = "on";
                app.Task8.ImageSource = "transparent.gif";
                app.currentHand(8).imagename_array="transparent.gif";
                app.Task8.Visible = "on";
                app.Task8.Enable = "off";
                %when you add a card to the pile - remove from taskDeck and add
                %to new array
                app.played_card = app.taskDeck(8).imagename_array;
                app.taskDeck(8).imagename_array = [];
                app.card_pile_array = [app.card_pile_array app.played_card];
                app.taskBarCalculator;
                app.clickSound;
                app.cardFunction;
                
                app.endTurn;
                app.playValue=1;
            else
                app.Task8.Position = [-7 4 159 188];
                app.Task8.Enable = "on";
            end
            
            
        end

        % Button pushed function: ShuffleButton
        function ShuffleButtonPushed(app, event)
            app.ShuffleDeck;
            msgbox("YOU SHUFFLED THE DECK :)")
        end

        % Value changed function: PLAYCARDButton
        function PLAYCARDButtonValueChanged(app, event)
            
            if app.playValue==0
                
                app.playValue=1;
            else
                msgbox("NOW CLICK ON THE CARD YOU WANT TO PLAY AGAIN")
            end
        end

        % Selection changed function: WhoPlaysFirstButtonGroup
        function WhoPlaysFirstButtonGroupSelectionChanged(app, event)
            selectedButton = app.WhoPlaysFirstButtonGroup.SelectedObject;
            if selectedButton == app.Player1Button
                app.myPlayerNum = 1;
                %app.otherPlayerNum = 2;
            elseif selectedButton == app.Player2Button
                app.myPlayerNum = 2;
                %app.otherPlayerNum = 1;
            else
                disp("Error, we are waiting for Kledi's imposter functions so that 3 plus players can play");
            end
            
            if(app.myPlayerNum == 1)
                app.CreateTaskDeck;
                app.CreateHand;
            elseif (app.myPlayerNum == 2)
                msgbox("WAIT FOR PLAYER 1 TO GO FIRST","modal");
                %read (and write?) stuff from player 1
            else
                app.WaitForOtherPlayer();
                app.UpdateStatus(app.GetCardName(app.myLastDrawnCard),...
                    app.GetCardName(app.otherPlayerLastDrawnCard));
                set(app.DrawButtonPushed,'Enable','on');
            end
            
        end

        % Callback function
        function volumeImageClicked(app, event)
            
        end

        % Image clicked function: HelpButton
        function HelpButtonImageClicked(app, event)
            helpScreen
        end

        % Button pushed function: QuitButton
        function QuitButtonPushed(app, event)
            quitGame=menu('Are you sure you want to quit?',"Yes, Quit Game","No, Don't Quit")
            if quitGame==1
                msgbox('Hope you had fun playing!')
                closereq
            else
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.minutesLabel = uilabel(app.UIFigure);
            app.minutesLabel.Text = 'minutes';
            app.secondsLabel = uilabel(app.UIFigure);
            app.secondsLabel.Text = 'seconds';
            app.numSeconds_Label = uilabel(app.UIFigure);
            app.numSeconds_Label.Text = '0';
            app.SYSTEMMESSAGESPanel = uipanel(app.UIFigure);
            app.SYSTEMMESSAGESPanel.Title = 'SYSTEM MESSAGES ';
            app.TextArea = uitextarea(app.SYSTEMMESSAGESPanel);
            app.TextArea.Value = {'Disaster! '; 'This is the imposter speaking.'; 'I will sabotage your tasks and lower your score. '; 'Watch the timer!'};
            app.CardTable = uiimage(app.UIFigure);
            app.CardTable.ImageSource = 'CardTable.gif';
            app.MapButton = uiimage(app.UIFigure);
            app.MapButton.ImageSource = 'MapButton.gif';
            app.PlayerTable0 = uiimage(app.UIFigure);
            app.PlayerTable0.ImageSource = 'CrewPurple.gif';
            app.TaskBarFiller = uilabel(app.UIFigure);
            app.TaskBarFiller.Text = '.';
            app.TaskBarEmpty = uiimage(app.UIFigure);
            app.TaskBarEmpty.ImageSource = 'TaskBarTransparent.gif';
            app.Deck = uiimage(app.UIFigure);
            app.Deck.ImageSource = 'DeckPile.gif';
            app.HelpButton = uiimage(app.UIFigure);
            app.HelpButton.ImageClickedFcn = createCallbackFcn(app, @HelpButtonImageClicked, true);
            app.HelpButton.ImageSource = 'HelpButton.gif';
            app.CardPile = uiimage(app.UIFigure);
            app.CardPile.ImageSource = 'DeckCardsPlayed.gif';
            app.PlayerTable4 = uiimage(app.UIFigure);
            app.PlayerTable4.ImageSource = 'CrewGreen.gif';
            app.PlayerTable3 = uiimage(app.UIFigure);
            app.PlayerTable3.ImageSource = 'CrewBrown.gif';
            app.PlayerTable2 = uiimage(app.UIFigure);
            app.PlayerTable2.ImageSource = 'CrewPink.gif';
            app.PlayerTable1 = uiimage(app.UIFigure);
            app.PlayerTable1.ImageSource = 'CrewBlue.gif';
            app.Task8 = uiimage(app.UIFigure);
            app.Task8.ImageClickedFcn = createCallbackFcn(app, @Task8ImageClicked, true);
            app.Task8.ImageSource = 'ProjTask6.gif';
            app.Task7 = uiimage(app.UIFigure);
            app.Task7.ImageClickedFcn = createCallbackFcn(app, @Task7ImageClicked, true);
            app.Task7.ImageSource = 'ProjTask7.gif';
            app.Task6 = uiimage(app.UIFigure);
            app.Task6.ImageClickedFcn = createCallbackFcn(app, @Task6ImageClicked, true);
            app.Task6.ImageSource = 'ProjTask8.gif';
            app.Task5 = uiimage(app.UIFigure);
            app.Task5.ImageClickedFcn = createCallbackFcn(app, @Task5ImageClicked, true);
            app.Task5.ImageSource = 'ProjTask1.gif';
            app.Task4 = uiimage(app.UIFigure);
            app.Task4.ImageClickedFcn = createCallbackFcn(app, @Task4ImageClicked, true);
            app.Task4.ImageSource = 'ProjTask2.gif';
            app.Task3 = uiimage(app.UIFigure);
            app.Task3.ImageClickedFcn = createCallbackFcn(app, @Task3ImageClicked, true);
            app.Task3.ImageSource = 'ProjTask3.gif';
            app.Task2 = uiimage(app.UIFigure);
            app.Task2.ImageClickedFcn = createCallbackFcn(app, @Task2ImageClicked, true);
            app.Task2.ImageSource = 'ProjTask4.gif';
            app.Task1 = uiimage(app.UIFigure);
            app.Task1.ImageClickedFcn = createCallbackFcn(app, @Task1ImageClicked, true);
            app.Task1.ImageSource = 'ProjTask5.gif';
            app.ShuffleButton = uibutton(app.UIFigure, 'push');
            app.ShuffleButton.ButtonPushedFcn = createCallbackFcn(app, @ShuffleButtonPushed, true);
            app.ShuffleButton.Icon = 'ShuffleCard.gif';
            app.ShuffleButton.Text = '';
            app.DrawButton = uibutton(app.UIFigure, 'push');
            app.DrawButton.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.DrawButton.Icon = 'DeckButton.gif';
            app.DrawButton.Text = '';
            app.PLAYCARDButton = uibutton(app.UIFigure, 'state');
            app.PLAYCARDButton.ValueChangedFcn = createCallbackFcn(app, @PLAYCARDButtonValueChanged, true);
            app.PLAYCARDButton.Icon = 'CrewWhite.gif';
            app.PLAYCARDButton.Text = {'PLAY'; 'CARD'};
            app.PLAYCARDButton.Value = true;
            app.TimeDisplay = uilabel(app.UIFigure);
            app.TimeDisplay.Text = '  TIME LEFT:';
            app.PlayedTask = uiimage(app.UIFigure);
            app.numMinutes_Label = uilabel(app.UIFigure);
            app.numMinutes_Label.Text = '7';
            app.RESULTSCREENPanel = uipanel(app.UIFigure);
            app.RESULTSCREENPanel.Enable = 'off';
            app.RESULTSCREENPanel.Title = 'You have achieved';
            app.RESULTSCREENPanel.Visible = 'off';
            app.WinImage = uiimage(app.RESULTSCREENPanel);
            app.WinImage.ImageSource = 'WinningScreen.gif';
            app.LoseImage = uiimage(app.RESULTSCREENPanel);
            app.LoseImage.ImageSource = 'LosingScreen.gif';
            app.Quit = uiimage(app.RESULTSCREENPanel);
            app.Quit.ImageSource = 'QuitButton.gif';
            app.PlayAgain = uiimage(app.RESULTSCREENPanel);
            app.PlayAgain.ImageSource = 'PlayAgainButton.gif';
            app.Player1Image = uiimage(app.RESULTSCREENPanel);
            app.Player1Image.ImageSource = 'CrewPink.gif';
            app.Player2Image = uiimage(app.RESULTSCREENPanel);
            app.Player2Image.ImageSource = 'CrewPink.gif';
            app.QuitButton = uibutton(app.UIFigure, 'push');
            app.QuitButton.ButtonPushedFcn = createCallbackFcn(app, @QuitButtonPushed, true);
            app.QuitButton.Icon = 'QuitButton.gif';
            app.QuitButton.Text = '';
            app.VOLUMEButtonGroup = uibuttongroup(app.UIFigure);
            app.VOLUMEButtonGroup.Title = 'VOLUME';
            app.ONButton = uiradiobutton(app.VOLUMEButtonGroup);
            app.ONButton.Text = 'ON';
            app.ONButton.Value = true;
            app.OFFButton = uiradiobutton(app.VOLUMEButtonGroup);
            app.OFFButton.Text = 'OFF';
            app.STARTSCREENPanel = uipanel(app.UIFigure);
            app.STARTSCREENPanel.Title = 'START SCREEN';
            app.startImage = uiimage(app.STARTSCREENPanel);
            app.startImage.ImageSource = 'AmongUsTitlePage.gif';
            app.STARTButton = uibutton(app.STARTSCREENPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.NumberOfPlayersPanel = uipanel(app.STARTSCREENPanel);
            app.NumberOfPlayersPanel.Title = 'Number Of Players';
            app.Num5Button = uibutton(app.NumberOfPlayersPanel, 'push');
            app.Num5Button.ButtonPushedFcn = createCallbackFcn(app, @Num5ButtonPushed, true);
            app.Num5Button.Text = '5';
            app.Num4Button = uibutton(app.NumberOfPlayersPanel, 'push');
            app.Num4Button.ButtonPushedFcn = createCallbackFcn(app, @Num4ButtonPushed, true);
            app.Num4Button.Text = '4';
            app.Num3Button = uibutton(app.NumberOfPlayersPanel, 'push');
            app.Num3Button.ButtonPushedFcn = createCallbackFcn(app, @Num3ButtonPushed, true);
            app.Num3Button.Text = '3';
            app.Num2Button = uibutton(app.NumberOfPlayersPanel, 'push');
            app.Num2Button.ButtonPushedFcn = createCallbackFcn(app, @Num2ButtonPushed, true);
            app.Num2Button.Text = '2';
            app.WhoPlaysFirstButtonGroup = uibuttongroup(app.STARTSCREENPanel);
            app.WhoPlaysFirstButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WhoPlaysFirstButtonGroupSelectionChanged, true);
            app.WhoPlaysFirstButtonGroup.Title = 'Which player are you?';
            app.Player1Button = uitogglebutton(app.WhoPlaysFirstButtonGroup);
            app.Player1Button.Text = 'PLAYER 1';
            app.Player1Button.Value = true;
            app.Player2Button = uitogglebutton(app.WhoPlaysFirstButtonGroup);
            app.Player2Button.Text = 'PLAYER 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIcomponentsWITHSOUNDV2_Alyssa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end