classdef thingspeaktaskbar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        SendchatButton    matlab.ui.control.Button
        Player1ChatLabel  matlab.ui.control.Label
        Player2ChatLabel  matlab.ui.control.Label
        Player1alwaysgoesfirstyouchoosePanel  matlab.ui.container.Panel
        Player1Button     matlab.ui.control.Button
        Player2Button     matlab.ui.control.Button
    end

    
    properties (Access = private)
        
        
        number %description
        writeChId= 1233879
        writeKey= %yfuycfcgyhj
        
    end 
    
        function taskbarThingspeak(app,event)
 if app.myPlayerNum = 1;
        thingSpeakWrite(app.writeChId, 'fields', 3, 'values', app.number, 'WriteKey', app.writeKey)
        app.Data=thingSpeakRead(app.writeChId); 
        app.ServerEditField.Value=app.Data 
 else 
        thingSpeakWrite(app.writeChId, 'fields', 3, 'values', app.number, 'WriteKey', app.writeKey)
        app.Data=thingSpeakRead(app.writeChId); 
        app.ServerEditField.Value=app.Data 
        
               end


        myPlayerNum % Description
        otherPlayerNum
        deck
        channelID
        writeKey
        readKey
        readDelay
        writeDelay
        answer
        otherString
        mysendString
        dataTable
        
    end
    
    methods (Access = private)
        
        function [] = ClearThingSpeakChannel(app)
            thingSpeakWrite(app.channelID, 'Fields', 1, 'Values', "0", 'WriteKey', app.writeKey);
           
        end
%         function [] = EndTurn(app)
%             while app.myPlayerNum
%         end


    function [] = ClearThingSpeakChannel(app)
            thingSpeakWrite(app.channelID, 'Fields', 1, 'Values', "0", 'WriteKey', app.writeKey);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.myPlayerNum = 1;
            app.otherPlayerNum = 2;
            app.deck = "";
            set(app.SendchatButton, 'Enable', 'off');
            
            
             % ASSIGN THE ID OF YOUR OWN CHANNEL HERE
            app.channelID = 1247940;
            
             % ASSIGN THE WRITE API KEY OF YOUR OWN CHANNEL HERE
            app.writeKey = 'EYDVQ60GG0L604FU';
            
             % ASSIGN THE READ API KEY OF YOUR OWN CHANNEL HERE
            app.readKey = 'S4GIV0DNOL2ZX6Z3';
            
            app.readDelay = 5;
            app.writeDelay = 1;
            app.ClearThingSpeakChannel();
        end

        % Callback function
        function sendDeckButtonPushed(app, event)
            taskDeck = 1;
        end

        % Button pushed function: SendchatButton
        function SendchatButtonPushed(app, event)
            prompt = {'Type a short message:'};
            dlgtitle = 'Emergency Meeting';
            dims = [1 75];
            definput = {'Type Here'};
            app.answer = inputdlg(prompt,dlgtitle,dims,definput);
            app.answer = string(app.answer);
            
            
                if(app.myPlayerNum == 1)
                    set(app.SendchatButton, 'Enable', 'on');
                    pause(app.readDelay);
                    %read old data to save other player chat message
                    app.dataTable = thingSpeakRead(app.channelID,"Fields",2,"ReadKey",app.readKey, 'OutputFormat',"table");
                    
                    %sends to command window for debugging
                    disp("Player 1 reads player 2 chat message from field 2")
                    disp(app.dataTable);
                    chatMessage= string(app.dataTable.chatMessage(1));
                    disp("This is" + " " + chatMessage)
                    
                    %update the player 2 label with their last chat message
                    app.Player2ChatLabel.Text = chatMessage;
                    
                    
                    %change text label to chat message
                    app.Player1ChatLabel.Text = app.answer;
                    
                    pause(app.writeDelay);
                    
                    %write your chatMessage string to the channel
                    
                    thingSpeakWrite(app.channelID, "Fields",2,"Values",app.answer,"WriteKey",app.writeKey);
                    disp("This is" + " " + app.answer);
                    
                    %player 1's turn is over
                    %set(app.SendchatButton, 'Visible', 'off');
                    %label: Do not click:wait for next player chat
                    app.myPlayerNum = 2;


                elseif(app.myPlayerNum == 2)
                    set(app.SendchatButton, 'Enable', 'on');
                    pause(app.readDelay);
                    
                    %read old data to save other player chat message
                    app.dataTable = thingSpeakRead(app.channelID,"Fields",2,"ReadKey",app.readKey, 'OutputFormat',"table");
                    
                    %sends to command window for debugging
                    disp("Player 2 reads player 1 chat message")
                    disp(app.dataTable);
                    chatMessage= string(app.dataTable.chatMessage(1));
                    disp("This is" + " " + chatMessage)
                    
                    %update the player 1 label with their last chat message
                    app.Player1ChatLabel.Text = chatMessage;
                    
                    %change text label to chat message
                    app.Player2ChatLabel.Text = app.answer;
                    
                    pause(app.writeDelay);
                    
                    %write your chatMessage string to the channel
                    thingSpeakWrite(app.channelID, "Fields",2,"Values",app.answer,"WriteKey",app.writeKey);
                    disp("This is" + " " + app.answer);
                    
                    %player 2's turn is over
                    app.myPlayerNum = 1;
                    %set(app.SendchatButton, 'Enable', 'off');
                    
                else
                    errordlg("Three to five imposter mode is being done by Kledi", "ERROR");
                end


        end

        % Button pushed function: Player1Button
        function Player1ButtonPushed(app, event)
            set(app.Player1Button, 'Enable', 'off');
            set(app.Player2Button, 'Enable', 'off');
            set(app.SendchatButton, 'Enable', 'on');
            
            app.myPlayerNum = 1;
            app.Player1ChatLabel.Position = [420,84,195,103];
            app.Player1ChatLabel.HorizontalAlignment = 'right';
            app.Player1ChatLabel.VerticalAlignment = 'bottom';
            app.Player2ChatLabel.Position = [420,186,195,111];
            app.Player2ChatLabel.HorizontalAlignment = 'left';
            app.Player2ChatLabel.VerticalAlignment = 'top';
           
        end

        % Button pushed function: Player2Button
        function Player2ButtonPushed(app, event)
            set(app.Player1Button, 'Enable', 'off');
            set(app.Player2Button, 'Enable', 'off');
            set(app.SendchatButton, 'Enable', 'on');
            
            
            app.myPlayerNum = 2;
            app.Player2ChatLabel.Position = [420,84,195,103];
            app.Player2ChatLabel.HorizontalAlignment = 'right'
            app.Player1ChatLabel.Position = [420,186,195,111];
            app.Player1ChatLabel.HorizontalAlignment = 'left'
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SendchatButton = uibutton(app.UIFigure, 'push');
            app.SendchatButton.ButtonPushedFcn = createCallbackFcn(app, @SendchatButtonPushed, true);
            app.SendchatButton.Text = 'Send chat';
            app.Player1ChatLabel = uilabel(app.UIFigure);
            app.Player1ChatLabel.Text = 'Player 1 Chat';
            app.Player2ChatLabel = uilabel(app.UIFigure);
            app.Player2ChatLabel.Text = 'Player 2 Chat';
            app.Player1alwaysgoesfirstyouchoosePanel = uipanel(app.UIFigure);
            app.Player1alwaysgoesfirstyouchoosePanel.Title = 'Player 1 always goes first, you choose...';
            app.Player1Button = uibutton(app.Player1alwaysgoesfirstyouchoosePanel, 'push');
            app.Player1Button.ButtonPushedFcn = createCallbackFcn(app, @Player1ButtonPushed, true);
            app.Player1Button.Text = 'Player 1';
            app.Player2Button = uibutton(app.Player1alwaysgoesfirstyouchoosePanel, 'push');
            app.Player2Button.ButtonPushedFcn = createCallbackFcn(app, @Player2ButtonPushed, true);
            app.Player2Button.Text = 'Player 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thingspeaktaskbar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end